classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TotaldetectadoLabel          matlab.ui.control.Label
        TipodeanalisisDropDown       matlab.ui.control.DropDown
        TipodeanalisisDropDownLabel  matlab.ui.control.Label
        ProcesarButton               matlab.ui.control.Button
        CargarImagenButton           matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ListaArchivos
        RutaCarpeta
    end
    
    methods (Access = private)
        
       function [numCells, rgbMask] = procesar_celulas(app, img)
            % 1. Pre-procesamiento
            if size(img, 3) == 3
                blueChannel = img(:,:,3);
            else
                blueChannel = img;
            end
            
            imgEnhanced = adapthisteq(blueChannel, 'ClipLimit', 0.02);
            
            imgSmooth = imgaussfilt(imgEnhanced, 2);
            
            bw = imbinarize(imgSmooth, 'adaptive', 'Sensitivity', 0.5, 'ForegroundPolarity','bright');
            
            bw = imfill(bw, 'holes');
            bw = bwareaopen(bw, 10); % Elimina ruido muy pequeño
            
            se = strel('disk', 2); 
            bw_separated = imopen(bw, se);
            
            D = bwdist(~bw_separated);
            D_inv = -D;
            
            mask = imextendedmin(D_inv, 2); 
            
            D_mod = imimposemin(D_inv, mask);
            L = watershed(D_mod);
            
            bw(L == 0) = 0;
            
            % 3. Resultados
            [labeledImage, numCells] = bwlabel(bw);
            rgbMask = label2rgb(labeledImage, 'jet', 'k', 'shuffle');
        end

        function [numVirus, rgbMask] = procesar_virus(app, img)
            % 1. Escala de grises e Inversión
            if size(img, 3) == 3
                gray = rgb2gray(img);
            else
                gray = img;
            end
            I_inv = imcomplement(gray);
            
            % 2. Top-Hat para fondos sucios
            se = strel('disk', 12);
            I_top = imtophat(I_inv, se);
            I_adj = imadjust(I_top);
            
            % 3. Binarización
            bw = imbinarize(I_adj, 'global');
            bw = bwareaopen(bw, 5); 
            bw = imclearborder(bw);
            
            % 4. Watershed simplificado
            D = bwdist(~bw);
            mask = imextendedmin(-D, 1);
            D_mod = imimposemin(-D, mask);
            L = watershed(D_mod);
            bw(L == 0) = 0;
            
            % 5. Resultados
            [L_final, numVirus] = bwlabel(bw);
            rgbMask = label2rgb(L_final, 'spring', 'k', 'shuffle');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            % 1. Abrir selector con MultiSelect activado
            [files, path] = uigetfile({'*.jpg;*.png;*.tif', 'Imágenes'}, ...
                                      'MultiSelect', 'on');
            
            if isequal(files, 0)
                return; % Cancelado
            end
            
            % 2. Convertir siempre a Cell Array (lista)
            % Si seleccionas 1 solo archivo, MATLAB devuelve char. 
            % Si seleccionas varios, devuelve cell. Lo normalizamos:
            if ischar(files)
                app.ListaArchivos = {files};
            else
                app.ListaArchivos = files;
            end
            
            app.RutaCarpeta = path;
            
            % 3. Feedback visual
            numFiles = length(app.ListaArchivos);
            app.TotaldetectadoLabel.Text = ['Archivos cargados: ' num2str(numFiles)];
            
            % Limpiar ejes
            cla(app.UIAxes);
            cla(app.UIAxes2);
        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            % Validación
            if isempty(app.ListaArchivos)
                uialert(app.UIFigure, 'Por favor cargue imágenes primero.', 'Error');
                return;
            end
            
            % 1. Leer la configuración del usuario (Manual)
            tipoAnalisis = app.TipodeanalisisDropDown.Value; % Ej: "Células" o "Virus"
            esModoVirus = contains(tipoAnalisis, 'Virus'); % Verdadero/Falso
            
            % Inicializar acumulador total
            granTotal = 0;
            numArchivos = length(app.ListaArchivos);
            
            % --- BUCLE DE PROCESAMIENTO ---
            for i = 1:numArchivos
                % a) Cargar imagen actual
                filename = app.ListaArchivos{i};
                fullPath = fullfile(app.RutaCarpeta, filename);
                I = imread(fullPath);
                
                % b) Mostrar imagen original
                imshow(I, 'Parent', app.UIAxes);
                title(app.UIAxes, ['Analizando: ', filename], 'Interpreter', 'none');
                
                % c) Aplicar algoritmo según lo que seleccionó el usuario
                conteoActual = 0;
                imgResultado = [];
                
                if esModoVirus
                    % Usar función de Virus para TODO el lote
                    [conteoActual, imgResultado] = procesar_virus(app, I);
                    etiqueta = 'Virus';
                else
                    % Usar función de Células para TODO el lote
                    [conteoActual, imgResultado] = procesar_celulas(app, I);
                    etiqueta = 'Celula';
                end
                
                % d) Acumular
                granTotal = granTotal + conteoActual;
                
                % e) Actualizar visualización
                imshow(imgResultado, 'Parent', app.UIAxes2);
                title(app.UIAxes2, ['Detectados en esta img: ', num2str(conteoActual)]);
                
                % f) Actualizar texto de estado
                app.TotaldetectadoLabel.Text = sprintf('Procesando %d/%d... (Total Acumulado: %d)', ...
                    i, numArchivos, granTotal);
                
                % g) Forzar refresco de pantalla y pequeña pausa
                drawnow;
                pause(0.5); % Ajusta este tiempo si quieres que vaya más rápido o lento
            end
            
            % --- FIN DEL PROCESO ---
            msg = sprintf('Análisis de %s completado.\n\nImágenes procesadas: %d\nTotal de elementos encontrados: %d', ...
                          etiqueta, numArchivos, granTotal);
            
            uialert(app.UIFigure, msg, 'Resultado Final');
            app.TotaldetectadoLabel.Text = ['Total Final: ', num2str(granTotal)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.ProcesarButton = uibutton(app.UIFigure, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = 'Procesar';
            app.TipodeanalisisDropDownLabel = uilabel(app.UIFigure);
            app.TipodeanalisisDropDownLabel.Text = 'Tipo de analisis';
            app.TipodeanalisisDropDown = uidropdown(app.UIFigure);
            app.TipodeanalisisDropDown.Items = {'Celula', 'Virus'};
            app.TipodeanalisisDropDown.Value = 'Celula';
            app.TotaldetectadoLabel = uilabel(app.UIFigure);
            app.TotaldetectadoLabel.Text = 'Total detectado: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end